classdef MilkyWay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SavePanel                      matlab.ui.container.Panel
        FileNameDisplay                matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        ChoosePathButton               matlab.ui.control.Button
        SaveFigureButton               matlab.ui.control.Button
        AccumulatePanel                matlab.ui.container.Panel
        AccumulateButton               matlab.ui.control.Button
        ScanningPanel                  matlab.ui.container.Panel
        ScanLengthSlider               matlab.ui.control.Slider
        ScanLengthSliderLabel          matlab.ui.control.Label
        ScanButton                     matlab.ui.control.Button
        CalibrationPanel               matlab.ui.container.Panel
        CentreFrequencySlider          matlab.ui.control.Slider
        CentreFrequencySliderLabel     matlab.ui.control.Label
        FFTSizeDropDown                matlab.ui.control.DropDown
        FFTSizeDropDownLabel           matlab.ui.control.Label
        CalibrationtimeEditField       matlab.ui.control.NumericEditField
        CalibrationtimeEditFieldLabel  matlab.ui.control.Label
        StartCalibrationButton         matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        WarningsLabel                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

  
    properties (Access = public)
        sdr                     % RTL-SDR object
        fftSize     
        avgBg                   % Background FFT vector
        centreFreq              % RTL-SDR centre frequency    
        freq                    % FFT frequency vector
        lastSamples             % For scanning mode, last n FFT results
        window                  % Hanning window
        plotLine                % To plot the hydrogen line on the graph
        filePath                % Location to save figures
        fileName                % Name of file
        closeReq                % For controlled app closure

        targetFreq = 1420.4057; % 21cm line
        sampleRate = 2.4e6      % RTL-SDR sample rate
        isScanning = false;
        isAccumulating = false;
    end
   
    methods (Access = public)
        function scanningLoop(app)

            numSamples = int32(app.ScanLengthSlider.Value);
            app.lastSamples = zeros(app.fftSize, numSamples);

            count = 0;

            % Loop to run while in the scanning phase
            while app.isScanning
                if mod(count, 200) == 0
                    doCheck = true;
                else
                    doCheck = false;
                end
                % Get latest fft vector
                newData = dataAnalysis(app, doCheck);
                app.StartCalibrationButton.Enable = 'off';

                % Append to previous vectors
                app.lastSamples(:, 1:end - 1) = app.lastSamples(:, 2:end);
                app.lastSamples(:, end) = newData;

                % Average last n number of samples
                toplot = mean(app.lastSamples, 2);

                plot(app.UIAxes, app.freq, toplot);
                drawnow limitrate; 
                if app.closeReq
                    onClose(app);
                    break
                end
            end
            if isvalid(app)
                app.StartCalibrationButton.Enable = 'on';
            end
        end

        function accumulationLoop(app)
            % Loop to run while in the accumulation phase
            app.StartCalibrationButton.Enable = 'off';
            data = zeros(app.fftSize, 1);
            count = 0;
            while app.isAccumulating
                if mod(count, 200) == 0
                    doCheck = true;
                else
                    doCheck = false;
                end
                % Add new data to existing vector
                newData = dataAnalysis(app, doCheck);
                data = data + newData;
                count = count + 1;
                plot(app.UIAxes, app.freq, data / count);
                drawnow limitrate;
                if app.closeReq
                    onClose(app);
                    break
                end
            end
            if isvalid(app)
                app.StartCalibrationButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStart(app)
            if isprop(app, 'sdr') && ~isempty(app.sdr) && isvalid(app.sdr)
                try
                    release(app.sdr);
                catch
                    % Suppress errors if the hardware wasn't responsive
                end
            delete(app.sdr);
            app.sdr = [];
            end
        end

        % Button pushed function: StartCalibrationButton
        function calibrateButtonPress(app, event)
            calibrate(app);
        end

        % Button pushed function: AccumulateButton
        function AccumButtonPress(app, event)
            toggleAccumulate(app);
        end

        % Button pushed function: ScanButton
        function scanButtonPress(app, event)
            toggleScan(app);  
        end

        % Button pushed function: ChoosePathButton
        function chooseFigPath(app, event)
            % Get user to pick the path to save figures to
            prevPath = app.filePath;
            prevPathExist = any(prevPath ~= 0);
            
            app.filePath = uigetdir(title='Choose a Folder');
            
            if ~(all(app.filePath == 0) && prevPathExist)
                updateFileName(app);
                if app.filePath ~=0
                    app.FileNameDisplay.FontColor = 'black';
                end
            else
                app.filePath = prevPath;
            end
        end

        % Button pushed function: SaveFigureButton
        function saveFig(app, event)
            % Saves figure to appropriate file
            if app.filePath ~= 0
                path = fullfile(app.filePath, app.fileName);
                exportgraphics(app.UIAxes, path);
            else
                app.FileNameDisplay.FontColor = 'red';
            end
        end

        % Value changed function: FileNameEditField
        function updateFileName(app, event)
            name = app.FileNameEditField.Value;
            time = string(datetime('now', format='HHmm'));
            app.fileName = strjoin([name, time, ".png"], "");
            
            if app.filePath ~= 0
                app.FileNameDisplay.Text = strjoin(["Filename:", app.fileName]);
            else
                 app.FileNameDisplay.Text = "Please choose a path";
            end
        end

        % Close request function: UIFigure
        function onClose(app, event)
            if app.isScanning || app.isAccumulating
                app.isScanning = false;
                app.isAccumulating = false;
                app.closeReq = true;
            else
                info = sdrinfo(app.sdr.RadioAddress);
                if ~isempty(info)
                    release(app.sdr);
                    fprintf("Released SDR\n");
                end
                delete(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {593, 593};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {216, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @onClose, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CalibrationPanel = uipanel(app.LeftPanel);
            app.CalibrationPanel.Title = 'Calibration';
            app.StartCalibrationButton = uibutton(app.CalibrationPanel, 'push');
            app.StartCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateButtonPress, true);
            app.StartCalibrationButton.Text = 'Start Calibration';
            app.CalibrationtimeEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CalibrationtimeEditFieldLabel.Text = 'Calibration time';
            app.CalibrationtimeEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.CalibrationtimeEditField.Limits = [0 300];
            app.CalibrationtimeEditField.Value = 60;
            app.FFTSizeDropDownLabel = uilabel(app.CalibrationPanel);
            app.FFTSizeDropDownLabel.Text = 'FFT Size';
            app.FFTSizeDropDown = uidropdown(app.CalibrationPanel);
            app.FFTSizeDropDown.Items = {'512', '1024', '2048', '4096'};
            app.FFTSizeDropDown.Value = '2048';
            app.CentreFrequencySliderLabel = uilabel(app.CalibrationPanel);
            app.CentreFrequencySliderLabel.Text = 'Centre Frequency';
            app.CentreFrequencySlider = uislider(app.CalibrationPanel);
            app.CentreFrequencySlider.Limits = [1415 1425];
            app.CentreFrequencySlider.Step = 1;
            app.CentreFrequencySlider.Value = 1420;
            app.ScanningPanel = uipanel(app.LeftPanel);
            app.ScanningPanel.Title = 'Scanning';
            app.ScanButton = uibutton(app.ScanningPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @scanButtonPress, true);
            app.ScanButton.Enable = 'off';
            app.ScanButton.Text = 'Scan';
            app.ScanLengthSliderLabel = uilabel(app.ScanningPanel);
            app.ScanLengthSliderLabel.Text = 'Scan Length';
            app.ScanLengthSlider = uislider(app.ScanningPanel);
            app.ScanLengthSlider.Limits = [100 10000];
            app.ScanLengthSlider.Value = 100;
            app.AccumulatePanel = uipanel(app.LeftPanel);
            app.AccumulatePanel.Title = 'Accumulate';
            app.AccumulateButton = uibutton(app.AccumulatePanel, 'push');
            app.AccumulateButton.ButtonPushedFcn = createCallbackFcn(app, @AccumButtonPress, true);
            app.AccumulateButton.Enable = 'off';
            app.AccumulateButton.Text = 'Accumulate';
            app.SavePanel = uipanel(app.LeftPanel);
            app.SavePanel.Title = 'Save';
            app.SaveFigureButton = uibutton(app.SavePanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @saveFig, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.ChoosePathButton = uibutton(app.SavePanel, 'push');
            app.ChoosePathButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFigPath, true);
            app.ChoosePathButton.Text = 'Choose Path';
            app.FileNameEditFieldLabel = uilabel(app.SavePanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SavePanel, 'text');
            app.FileNameEditField.CharacterLimits = [1 10];
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @updateFileName, true);
            app.FileNameEditField.Placeholder = 'filename';
            app.FileNameEditField.Value = 'fig';
            app.FileNameDisplay = uilabel(app.SavePanel);
            app.FileNameDisplay.Text = 'Please choose a path';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Frequency (MHz)')
            ylabel(app.UIAxes, 'Relative Intensity')
            zlabel(app.UIAxes, 'Z')
            app.WarningsLabel = uilabel(app.RightPanel);
            app.WarningsLabel.Visible = 'off';
            app.WarningsLabel.Text = 'Warnings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MilkyWay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end